classdef app_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FUCCIAnnotationTool          matlab.ui.Figure
        UploadimagesButton           matlab.ui.control.Button
        original_image               matlab.ui.control.UIAxes
        segmented_image              matlab.ui.control.UIAxes
        SaveButton                   matlab.ui.control.Button
        ResettooriginalviewsButton   matlab.ui.control.Button
        SetlabelEditFieldLabel       matlab.ui.control.Label
        SetlabelEditField            matlab.ui.control.EditField
        Previous_labeled_Image       matlab.ui.control.UIAxes
        ClearButton                  matlab.ui.control.Button
        LabelEditField_2             matlab.ui.control.EditField
        TexasSensitivitySliderLabel  matlab.ui.control.Label
        TexasSensitivitySlider       matlab.ui.control.Slider
        ButtonGroup                  matlab.ui.container.ButtonGroup
        TexasRedButton               matlab.ui.control.RadioButton
        YFPButton                    matlab.ui.control.RadioButton
        PreviousButton               matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        OverlaymaskCheckBox          matlab.ui.control.CheckBox
        FirstButton                  matlab.ui.control.Button
        LastButton                   matlab.ui.control.Button
        YFPSensitivitySliderLabel    matlab.ui.control.Label
        YFPSensitivitySlider         matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)


        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    NextframeButtonPushed(app, event);
                case 'leftarrow'
                    PreviousframeButtonPushed(app, event);
            end
        end
    end
    
    methods (Access = public)
        
        function [img_binary,color_mask] = create_mask(app,img,sensitivity)
            img_scaled= rescale(img);
            img_adapt_histeq= adapthisteq(img_scaled);
            img_binary =imbinarize(img_adapt_histeq,'adaptive','Sensitivity',sensitivity);
            img_binary=bwareaopen(img_binary,round(0.0002*numel(img)));
            img_binary = imfill(img_binary,'holes');
            se = strel('disk',6);
            img_binary = imclose(img_binary,se);
            img_binary = imfill(img_binary,'holes');
            img_binary=bwareaopen(img_binary,round(0.0002*numel(img)));
            img_ws=ws_fun(app,img_binary);
            color_mask = label2rgb(img_ws,'jet',[.5 .5 .5]);
            img_binary=img_ws>0;
        end
        
        function display_img(app)
              global listing img_dir yfp_sen texas_sen img_adapt_histeq pos_list_label img_name current_idx yfp_texas_mask img_h img_w mask_list_label mask_list_cell img_list img_name_list
              global texas_img_list yfp_img_list
              img_name=listing(current_idx).name;  
              img=imread(strcat(img_dir,'/',img_name)); 
              img_h=452;img_w=612;
              img=imresize(img,[img_h,img_w]);
              
%               [img_h,img_w]=size(img);
              name_split=strsplit(img_name,'_');
              name_split{3}='2';
              name_split{5}='YFP';
              yfp=imread(strcat(img_dir,'/',strjoin(name_split,'_'))); 
              yfp=imresize(yfp,[img_h,img_w]);
              [yfp_mask,yfp_color_mask] = create_mask(app,yfp,yfp_sen);
              name_split{3}='3';
              name_split{5}='Texas Red';
              texas=imread(strcat(img_dir,'/',strjoin(name_split,'_'))); 
              texas=imresize(texas,[img_h,img_w]);
              [texas_mask,texas_color_mask] = create_mask(app,texas,texas_sen);
              yfp_texas_mask=texas_mask|yfp_mask;
              img_ws=ws_fun(app,yfp_texas_mask);
              yfp_texas_color_mask = label2rgb(img_ws,'jet',[.5 .5 .5]);
              yfp_texas_mask=img_ws>0;
%               imwrite(im2uint8(yfp_mask),'out.png','png','BitDepth',8, 'mode', 'lossless')
              if length(size(img))==3
                  img=rgb2gray(img);
              end
              img_scaled= rescale(img);
              img_adapt_histeq= adapthisteq(img_scaled);
              texas_adapt_hist=adapthisteq(rescale(texas));
              yfp_adapt_hist=adapthisteq(rescale(yfp));
              if app.TexasRedButton.Value
                  if app.OverlaymaskCheckBox.Value
                       color_mask_blend=imfuse(texas_adapt_hist,texas_color_mask,'blend','Scaling','joint');
                       imshow(color_mask_blend,'Parent',app.Previous_labeled_Image)
%                       imshow(imfuse(texas_adapt_hist,texas_mask,'blend'),'Parent',app.Previous_labeled_Image)
                  else
                      imshow(texas_adapt_hist,'Parent',app.Previous_labeled_Image)
                  end
              else
                  if app.OverlaymaskCheckBox.Value
                      color_mask_blend=imfuse(yfp_adapt_hist,yfp_color_mask,'blend','Scaling','joint');
                      imshow(color_mask_blend,'Parent',app.Previous_labeled_Image)
%                       imshow(imfuse(yfp_adapt_hist,yfp_mask,'blend'),'Parent',app.Previous_labeled_Image)
                  else
                      imshow(yfp_adapt_hist,'Parent',app.Previous_labeled_Image)
                  end
              end
%               img_adapt_histeq= img_scaled;
              if length(pos_list_label)<current_idx
                    mask_list_cell{current_idx}=yfp_texas_mask;
                    mask_list_label{current_idx}=im2uint8(yfp_texas_mask);
                    img_list{current_idx}=img_adapt_histeq;
                    img_name_list{current_idx}=img_name;
                    texas_img_list{current_idx}=texas_adapt_hist;
                    yfp_img_list{current_idx}=yfp_adapt_hist;
              end
              color_mask_blend=imfuse(img_adapt_histeq,yfp_texas_color_mask,'blend','Scaling','joint');
              imshow(color_mask_blend,'Parent',app.original_image)
%               imshow(imfuse(img_adapt_histeq,yfp_texas_mask,'blend'),'Parent',app.original_image)
              imshow(mask_list_label{current_idx},'Parent',app.segmented_image)
              app.LabelEditField_2.Value=img_name;
            
        end
        
        function update_mask_label(app)
            global current_idx yfp_texas_mask mask_list_label label_data
            mask_label=im2uint8(yfp_texas_mask);
            if ~isempty(label_data)
               
                for i=1:length(label_data)
                    
                    label=label_data{i}{1};
                    pose_x=label_data{i}{2}(1);
                    pose_y=label_data{i}{2}(2);
                    mask_label=insertText(mask_label,[pose_x,pose_y],label,'FontSize',14,'TextColor','red');
                end
            end
            mask_list_label{current_idx}=mask_label;
            
        end


        function img_ws = ws_fun(app,img_binary)
            dist = -bwdist(~img_binary);
            mask1 = imextendedmin(dist,2);
            mask2 = imimposemin(dist,mask1);
            img_ws = watershed(mask2);
            img_ws(~img_binary) = 0;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           global img_path_save
           %img_name=fullfile(ctfroot,'DeepSeas.png');
           img_name='DeepSeas.png';
           axis(app.segmented_image, 'tight')
           imshow(img_name,'Parent',app.segmented_image)
           axis(app.original_image, 'tight')
           imshow(img_name,'Parent',app.original_image)
           axis(app.Previous_labeled_Image, 'tight')
           imshow(img_name,'Parent',app.Previous_labeled_Image)
           img_path_save=pwd;

        end

        % Button pushed function: UploadimagesButton
        function UploadimagesButtonPushed(app, event)

        global img_dir current_idx texas_sen yfp_sen label_data img_list img_path_save img_name_list texas_img_list yfp_img_list
        global num_img listing
        global mask_list_cell mask_list_label
        global pos_list_label
        if img_path_save
            img_dir=uigetdir(img_path_save);
        else
            img_dir=uigetdir(cd);
        end

        figure(app.FUCCIAnnotationTool)
        if (img_dir)
            
        listing = dir([img_dir '/*.tif']);
        
        num_img=(length(listing))/3;

        current_idx=1;
        texas_sen=0;
        yfp_sen=0;
        app.TexasSensitivitySlider.Value=0;
        app.YFPSensitivitySlider.Value=0;
        img_path_save=img_dir;
        mask_list_cell={};
        pos_list_label={};
        mask_list_label={};
        img_list={};
        texas_img_list={};
        yfp_img_list={};
        img_name_list={};
        label_data={};
        update_mask_label(app);
        display_img(app);  


        end
        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            global img_list  img_name_list mask_list_cell mask_list_label texas_img_list yfp_img_list
            global img_path_save
            global pos_list_label
            
            [file,path] = uiputfile([img_path_save '/all']);
 
            figure(app.FUCCIAnnotationTool)
            if (file)
            img_path_save=path;
            
            if ~isfolder(fullfile(path,'phase_contrast_images'))
                mkdir(fullfile(path,'phase_contrast_images'))
            end
            if ~isfolder(fullfile(path,'texas_red_images'))
                mkdir(fullfile(path,'texas_red_images'))
            end
            if ~isfolder(fullfile(path,'yfp_images'))
                mkdir(fullfile(path,'yfp_images'))
            end
            if ~isfolder(fullfile(path,'mask_images'))
                mkdir(fullfile(path,'mask_images'))
            end
            if ~isfolder(fullfile(path,'labeled_images'))
                mkdir(fullfile(path,'labeled_images'))
            end
            
            if ~isfolder(fullfile(path,'data_reports'))
                mkdir(fullfile(path,'data_reports'))
            end
            texas_hash=containers.Map;yfp_hash=containers.Map;
            label_tidy=cell(1,1);pos_x_tidy=[];pos_y_tidy=[];texas_intesnsity_tidy=[];yfp_intensity_tidy=[];
            texas_channel_tidy=cell(1,1);yfp_channel_tidy=cell(1,1);source_name_tidy=cell(1,1);
            tidy_idx=1;
            for i=1:length(img_list)
                imwrite(im2uint8(img_list{i}),fullfile(path,'phase_contrast_images',strrep(img_name_list{i},'tif','png')),'png','BitDepth',8, 'mode', 'lossless')
                imwrite(im2uint8(texas_img_list{i}),fullfile(path,'texas_red_images',strrep(img_name_list{i},'tif','png')),'png','BitDepth',8, 'mode', 'lossless')
                imwrite(im2uint8(yfp_img_list{i}),fullfile(path,'yfp_images',strrep(img_name_list{i},'tif','png')),'png','BitDepth',8, 'mode', 'lossless')
                imwrite(im2uint8(mask_list_cell{i}),fullfile(path,'mask_images',strrep(img_name_list{i},'tif','png')),'png','BitDepth',8, 'mode', 'lossless')
                imwrite(im2uint8(mask_list_label{i}),fullfile(path,'labeled_images',strrep(img_name_list{i},'tif','png')),'png','BitDepth',8, 'mode', 'lossless')
                if i<=length(pos_list_label)
                label_data_to_save=pos_list_label{i};
                    label=cell(length(label_data_to_save),1);pos_x=[];pos_y=[];texas_intesnsity=[];yfp_intensity=[];
                    for j=1:length(label_data_to_save)
                        
                        label{j} =label_data_to_save{j}{1};
                        texas_channel_tidy{tidy_idx,1}={'Texas_Red'};
                        yfp_channel_tidy{tidy_idx,1}={'YFP'};
                        label_tidy{tidy_idx,1}=label_data_to_save{j}{1};
                        source_name_tidy{tidy_idx,1}={img_name_list{i}};
                        tidy_idx=tidy_idx+1;
                        pos_x = [pos_x;label_data_to_save{j}{2}(1)];
                        pos_x_tidy = [pos_x_tidy;label_data_to_save{j}{2}(1)];
                        pos_y = [pos_y;label_data_to_save{j}{2}(2)];
                        pos_y_tidy = [pos_y_tidy;label_data_to_save{j}{2}(2)];
                        texas_intesnsity=[texas_intesnsity;round(label_data_to_save{j}{3}(1),2)];
                        texas_intesnsity_tidy=[texas_intesnsity_tidy;round(label_data_to_save{j}{3}(1),2)];
                        yfp_intensity=[yfp_intensity;round(label_data_to_save{j}{3}(2),2)];
                        yfp_intensity_tidy=[yfp_intensity_tidy;round(label_data_to_save{j}{3}(2),2)];
                        if isKey(texas_hash,label_data_to_save{j}{1})
                           texas_hash(label_data_to_save{j}{1})=[texas_hash(label_data_to_save{j}{1}) texas_intesnsity(end)] ;
                        else
                           texas_hash(label_data_to_save{j}{1})= texas_intesnsity(end);
                        end
                        if isKey(yfp_hash,label_data_to_save{j}{1})
                           yfp_hash(label_data_to_save{j}{1})=[yfp_hash(label_data_to_save{j}{1}) yfp_intensity(end)] ;
                        else
                           yfp_hash(label_data_to_save{j}{1})= yfp_intensity(end);
                        end
                    end
                    T = table(label,pos_x,pos_y,texas_intesnsity,yfp_intensity);
                    writetable(T,fullfile(path,'data_reports',strrep(img_name_list{i},'tif','txt')));
                end
            end
            intensity=[texas_intesnsity_tidy;yfp_intensity_tidy];
            channel=[texas_channel_tidy;yfp_channel_tidy];
            label=[label_tidy;label_tidy];
            pos_x=[pos_x_tidy;pos_x_tidy];
            pos_y=[pos_y_tidy;pos_y_tidy];
            source_name=[source_name_tidy;source_name_tidy];
            T = table(source_name,label,pos_x,pos_y,intensity,channel);
            writetable(T,fullfile(path,'data_reports','tidy_data.csv'));
            f = figure('visible','off');
            for i=keys(yfp_hash)
              plot(yfp_hash(i{1}),'g') 
              hold on  
            end

            for i=keys(texas_hash)
              plot(texas_hash(i{1}),'r')  
              hold on  
            end
            
            saveas(f,fullfile(path,'data_reports','intensity_plot.png'))
            
            end
        end

        % Button pushed function: ResettooriginalviewsButton
        function ResettooriginalviewsButtonPushed(app, event)

            app.original_image.XLimMode = 'auto';
            app.original_image.YLimMode = 'auto';
            app.segmented_image.XLimMode = 'auto';
            app.segmented_image.YLimMode = 'auto';
            app.Previous_labeled_Image.XLimMode = 'auto';
            app.Previous_labeled_Image.YLimMode = 'auto';

        end

        % Callback function
        function CropthecellbodyButtonPushed(app, event)
       
            
        end

        % Callback function
        function UndoButtonPushed(app, event)
            global mask_cell mask_list_cell mask_num_cell
            global img_adapt_histeq
            global mask_label mask_list_label  pos_list_label
            global color_mask_blend
            if(mask_num_cell>1)
            mask_num_cell=mask_num_cell-1;
            mask_cell=mask_list_cell{mask_num_cell};
            mask_label=mask_list_label{mask_num_cell};
            pos_list_label=pos_list_label(1:mask_num_cell,:);
            end
            color_mask_blend=imfuse(mask_cell,img_adapt_histeq,'blend');
            color_mask_blend=imfuse(color_mask_blend,mask_label,'blend');
            imshow(color_mask_blend,'Parent',app.segmented_image)
        end

        % Window button down function: FUCCIAnnotationTool
        function FUCCIAnnotationToolWindowButtonDown(app, event)
            global current_idx img_h img_w yfp_texas_mask pos_list_label label_data texas_img_list yfp_img_list
            
            point= get(app.original_image, 'CurrentPoint');
            x=uint16(point(1,1));
            y=uint16(point(1,2));
            if(app.SetlabelEditField.Value) & x>0 & x<=img_w & y>0 & y<=img_h & yfp_texas_mask(y,x)~=0
                label=app.SetlabelEditField.Value;
                [L,n] = bwlabeln(yfp_texas_mask);
               is_valid=0;
                for i=1:n
                    tmp_mask=L==i;
                    if tmp_mask(y,x)~=0
                        is_valid=1;
                        break
                    end
                end     
                if is_valid
                 texas=texas_img_list{current_idx};
                 yfp=yfp_img_list{current_idx};
                 texas_masked=texas.*tmp_mask;
                 inverted_mask=texas.*~yfp_texas_mask;
                 tmp1=texas_masked(texas_masked>0);
                 stats=regionprops(tmp_mask,'BoundingBox');
                 bbox=stats.BoundingBox;
                 inverted_mask=inverted_mask(max(bbox(2)-20,1):min(bbox(2)+bbox(4)+20,size(inverted_mask,1)),max(bbox(1)-20,1):min(bbox(1)+bbox(3)+20,size(inverted_mask,2)));
                 tmp2=inverted_mask(inverted_mask>0);
                 texas_intensity=mean(tmp1)-mean(tmp2);
                 yfp_masked=yfp.*tmp_mask;
                 inverted_mask=yfp.*~yfp_texas_mask;
                 tmp1=yfp_masked(yfp_masked>0);
                 inverted_mask=inverted_mask(max(bbox(2)-20,1):min(bbox(2)+bbox(4)+20,size(inverted_mask,1)),max(bbox(1)-20,1):min(bbox(1)+bbox(3)+20,size(inverted_mask,2)));
                 tmp2=inverted_mask(inverted_mask>0);
                 yfp_intensity=mean(tmp1)-mean(tmp2);

          
                stats = regionprops(tmp_mask,'Centroid');
                pos=floor(stats(1).Centroid);
                label_data{end+1}={label,pos,[texas_intensity,yfp_intensity]};
                pos_list_label{current_idx}=label_data;
                update_mask_label(app)
                display_img(app);
                NextButtonPushed(app)
                end
               
            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            global mask_list_cell mask_list_label yfp_texas_mask label_data current_idx
              
            answer = questdlg('Clear Annotated Image?','Clear','Yes','No','No');
            if isequal(answer,'Yes') 
                
              mask_list_cell{current_idx}=yfp_texas_mask;
              mask_list_label{current_idx}=im2uint8(yfp_texas_mask);
              label_data={};
              display_img(app);
            end
        end

        % Callback function
        function SliderValueChanged(app, event)
            
            value = floor(app.YFPSensitivitySlider.Value);
            display_seq(app,value-1);
       
            
        end

        % Callback function
        function LoadButtonPushed(app, event)

        end

        % Callback function
        function HelpButtonPushed(app, event)
            winopen('MATLAB_Annotation_Software_Toturial.pdf')
        end

        % Value changing function: TexasSensitivitySlider
        function TexasSensitivitySliderValueChanging(app, event)
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            display_img(app);
        end

        % Value changed function: TexasSensitivitySlider
        function TexasSensitivitySliderValueChanged(app, event)
            value = app.TexasSensitivitySlider.Value;
            global texas_sen  pos_list_label current_idx 
            if length(pos_list_label)<current_idx
            texas_sen = value/100;
            display_img(app);

            else
                app.TexasSensitivitySlider.Value=100*texas_sen;
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global num_img current_idx pos_list_label label_data
            if current_idx<num_img
                current_idx=current_idx+1;
                if length(pos_list_label)>=current_idx
                    label_data=pos_list_label{current_idx};
                else
                    label_data={};
                end
                display_img(app);
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            global current_idx pos_list_label label_data
            if current_idx>1
                current_idx=current_idx-1; 
                if length(pos_list_label)>=current_idx
                    label_data=pos_list_label{current_idx};
                else
                    label_data={};
                end    
%                 label_data=mask_list_label{current_idx};
                display_img(app);
            end
        end

        % Value changed function: SetlabelEditField
        function SetlabelEditFieldValueChanged(app, event)
            value = app.SetlabelEditField.Value;
            
        end

        % Value changed function: OverlaymaskCheckBox
        function OverlaymaskCheckBoxValueChanged(app, event)
            value = app.OverlaymaskCheckBox.Value;
            display_img(app);
        end

        % Key press function: FUCCIAnnotationTool
        function FUCCIAnnotationToolKeyPress(app, event)
            key = event.Key;
            if isequal(key,'rightarrow')
                NextButtonPushed(app)
            elseif isequal(key,'leftarrow')
                PreviousButtonPushed(app)
                
            end
        end

        % Button pushed function: FirstButton
        function FirstButtonPushed(app, event)
            global current_idx pos_list_label label_data
                current_idx=1; 
                if length(pos_list_label)>=current_idx
                    label_data=pos_list_label{current_idx};
                else
                    label_data={};
                end    
                display_img(app);
        end

        % Button pushed function: LastButton
        function LastButtonPushed(app, event)
            global current_idx pos_list_label label_data num_img
                current_idx=num_img; 
                if length(pos_list_label)>=current_idx
                    label_data=pos_list_label{current_idx};
                else
                    label_data={};
                end    
                display_img(app);
        end

        % Value changed function: YFPSensitivitySlider
        function YFPSensitivitySliderValueChanged(app, event)
            value = app.YFPSensitivitySlider.Value;
            global yfp_sen  pos_list_label current_idx 
            if length(pos_list_label)<current_idx
            yfp_sen = value/100;
            display_img(app);

            else
                app.YFPSensitivitySlider.Value=100*yfp_sen;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FUCCIAnnotationTool = uifigure('Visible', 'off');
            app.FUCCIAnnotationTool.Name = 'FUCCIAnnotationTool';
            app.FUCCIAnnotationTool.WindowButtonDownFcn = createCallbackFcn(app, @FUCCIAnnotationToolWindowButtonDown, true);
            app.FUCCIAnnotationTool.KeyPressFcn = createCallbackFcn(app, @FUCCIAnnotationToolKeyPress, true);
            app.UploadimagesButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.UploadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimagesButtonPushed, true);
            app.UploadimagesButton.Text = 'Upload images';
            app.original_image = uiaxes(app.FUCCIAnnotationTool);
            title(app.original_image, '')
            app.original_image.XLim = [0 1];
            app.original_image.XTickLabel = '';
            app.original_image.YTickLabel = '';
            app.segmented_image = uiaxes(app.FUCCIAnnotationTool);
            title(app.segmented_image, 'Annotated Image')
            app.segmented_image.XTickLabel = '';
            app.segmented_image.YTickLabel = '';
            app.SaveButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResettooriginalviewsButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.ResettooriginalviewsButton.ButtonPushedFcn = createCallbackFcn(app, @ResettooriginalviewsButtonPushed, true);
            app.ResettooriginalviewsButton.Text = 'Reset to original views';
            app.SetlabelEditFieldLabel = uilabel(app.FUCCIAnnotationTool);
            app.SetlabelEditFieldLabel.Text = 'Set label';
            app.SetlabelEditField = uieditfield(app.FUCCIAnnotationTool, 'text');
            app.SetlabelEditField.ValueChangedFcn = createCallbackFcn(app, @SetlabelEditFieldValueChanged, true);
            app.Previous_labeled_Image = uiaxes(app.FUCCIAnnotationTool);
            title(app.Previous_labeled_Image, 'Fluorescent Channels')
            app.Previous_labeled_Image.XTickLabel = '';
            app.Previous_labeled_Image.YTickLabel = '';
            app.ClearButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.LabelEditField_2 = uieditfield(app.FUCCIAnnotationTool, 'text');
            app.TexasSensitivitySliderLabel = uilabel(app.FUCCIAnnotationTool);
            app.TexasSensitivitySliderLabel.Text = 'Texas Sensitivity';
            app.TexasSensitivitySlider = uislider(app.FUCCIAnnotationTool);
            app.TexasSensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @TexasSensitivitySliderValueChanged, true);
            app.TexasSensitivitySlider.ValueChangingFcn = createCallbackFcn(app, @TexasSensitivitySliderValueChanging, true);
            app.ButtonGroup = uibuttongroup(app.FUCCIAnnotationTool);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TexasRedButton = uiradiobutton(app.ButtonGroup);
            app.TexasRedButton.Text = 'Texas Red';
            app.TexasRedButton.Value = true;
            app.YFPButton = uiradiobutton(app.ButtonGroup);
            app.YFPButton.Text = 'YFP';
            app.PreviousButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.OverlaymaskCheckBox = uicheckbox(app.FUCCIAnnotationTool);
            app.OverlaymaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlaymaskCheckBoxValueChanged, true);
            app.OverlaymaskCheckBox.Text = 'Overlay mask';
            app.FirstButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.FirstButton.ButtonPushedFcn = createCallbackFcn(app, @FirstButtonPushed, true);
            app.FirstButton.Text = 'First';
            app.LastButton = uibutton(app.FUCCIAnnotationTool, 'push');
            app.LastButton.ButtonPushedFcn = createCallbackFcn(app, @LastButtonPushed, true);
            app.LastButton.Text = 'Last';
            app.YFPSensitivitySliderLabel = uilabel(app.FUCCIAnnotationTool);
            app.YFPSensitivitySliderLabel.Text = 'YFP Sensitivity';
            app.YFPSensitivitySlider = uislider(app.FUCCIAnnotationTool);
            app.YFPSensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @YFPSensitivitySliderValueChanged, true);
            % Show the figure after all components are created
            app.FUCCIAnnotationTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FUCCIAnnotationTool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FUCCIAnnotationTool)
        end
    end
end